classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ReadoutRateEditField        matlab.ui.control.NumericEditField
        ReadoutRateEditFieldLabel   matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        y_coordEditField            matlab.ui.control.NumericEditField
        y_coordEditFieldLabel       matlab.ui.control.Label
        RamanShiftEditField         matlab.ui.control.NumericEditField
        RamanShiftEditFieldLabel    matlab.ui.control.Label
        folderPathTextbox           matlab.ui.control.EditField
        MapFolderButton             matlab.ui.control.Button
        PCAButton                   matlab.ui.control.Button
        x_coordEditField            matlab.ui.control.NumericEditField
        x_coordEditFieldLabel       matlab.ui.control.Label
        Y_NumEditField              matlab.ui.control.NumericEditField
        Y_NumEditFieldLabel         matlab.ui.control.Label
        Y_StepEditField             matlab.ui.control.NumericEditField
        Y_StepEditFieldLabel        matlab.ui.control.Label
        Y_StartEditField            matlab.ui.control.NumericEditField
        Y_StartEditFieldLabel       matlab.ui.control.Label
        X_NumEditField              matlab.ui.control.NumericEditField
        X_NumEditFieldLabel         matlab.ui.control.Label
        X_StepEditField             matlab.ui.control.NumericEditField
        X_StepEditFieldLabel        matlab.ui.control.Label
        X_StartEditField            matlab.ui.control.NumericEditField
        Slider                      matlab.ui.control.Slider
        X_StartEditFieldLabel       matlab.ui.control.Label
        MapButton                   matlab.ui.control.Button
        ImportBackgroundDataButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        background 
        map
        RS
        map_RS
        map_i
        folderPath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportBackgroundDataButton
        function ImportBackgroundDataButtonPushed(app, event)
            [file,path,~]=uigetfile('*.tif*');
            app.background = [path file];
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            folder = app.folderPathTextbox.Value;
            x_start = app.X_StartEditField.Value;
            x_step = app.X_StepEditField.Value;
            x_num = app.X_NumEditField.Value;

            y_start = app.Y_StartEditField.Value;
            y_step = app.Y_StepEditField.Value;
            y_num = app.Y_NumEditField.Value;

            %Detector Information
            RR=app.ReadoutRateEditField.Value;
            ET=app.ExposureTimeEditField.Value;
            
            %smoothing
            window_size = 11; % window size (odd number)
            degree = 3; % polynomial degree

            % Preallocate map variables
            app.map=zeros(x_num,y_num,1600);
            x_coord = x_start:x_step:(x_start + x_step * x_num);
            y_coord = y_start:y_step: (y_start + y_step * y_num);
            

            %Wavenumber Calibration
            pixel=1:1600; % each sample has 1600 data points
            
            Y=[797; 858; 1236; 1324; 1560; 1611; 1649];
            
            % X matrix defined so that the solution=[m;c]
            X=[216 1; 235 1; 356 1; 385 1; 465 1; 483 1; 496 1];
            
            solutions = mldivide(X,Y);
            
            app.RS = pixel.*solutions(1)+solutions(2); %calibrated curve equation to define the Raman Shifts

            %code reeads all the map files and creates a matrix where
            % row = x, column = y, z= spectrum data.
            
            for a =1:y_num
                for b=1:x_num
                    file = [folder '/Map_Andor_NaN_' num2str(x_coord(b)) '_' num2str(y_coord(a)) '.tif'];
                        
                    I=double(imread(file)-imread(app.background));

                    %cosmic ray removal
                    [I]=cosmicraystatistical(I,RR,ET);
            
                    %conversion of spectral information into 1D vector
                    data = mean(I)';
                    
                    %baseline correction
                    [~, data]=baseline(data);    
                    
                    %Smoothing of the Data
                    data= sgolayfilt(data, degree, window_size);

                    %Normalization and storage of data
                    app.map(a,b,:)=data./trapz(data);
                end
            end

            app.Slider.Value = 450;
            app.RamanShiftEditField.Value=app.Slider.Value;
            shift = app.Slider.Value;
            
            i_RS = find(app.RS < shift + 20 & app.RS > shift - 20);
            app.map_i = squeeze(mean(app.map(:,:,i_RS),3));
            % Set the x-axis and y-axis limits
            xlim(app.UIAxes, [0 x_num]);
            ylim(app.UIAxes, [0 y_num]);
            
            % Set the title of the UIAxes component
            title(app.UIAxes, ['Raman Map ' num2str(round(shift-20)) ' - ' num2str(round(shift+20)) '  cm^{-1}']);
            
            % Turn off the axis
            axis(app.UIAxes, 'off');
            
            image(app.UIAxes, app.map_i, 'CDataMapping', 'scaled');

        end

        % Button pushed function: MapFolderButton
        function MapFolderButtonPushed(app, event)
            % This function is called when the user clicks a button called "chooseFolderButton"
            % in the App Designer GUI
        
            % Open a dialog box to allow the user to choose a folder
            app.folderPath = uigetdir();
        
            % If the user cancels the dialog box, do nothing
            if app.folderPath == 0
                return;
            end
        
            % Update the text box in the GUI to display the selected folder path
            app.folderPathTextbox.Value = app.folderPath;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            x_num = app.X_NumEditField.Value;

            y_num = app.Y_NumEditField.Value;
            
            shift = app.Slider.Value;
            app.RamanShiftEditField.Value=app.Slider.Value;
            
            i_RS = find(app.RS < shift +20 & app.RS > shift - 20);
            app.map_i = squeeze(mean(app.map(:,:,i_RS),3));
            % Set the x-axis and y-axis limits
            xlim(app.UIAxes, [0 x_num]);
            ylim(app.UIAxes, [0 y_num]);
            
            % Set the title of the UIAxes component
            title(app.UIAxes, ['Raman Map ' num2str(shift-20) ' - ' num2str(shift+20) '  cm^{-1}']);
            
            % Turn off the axis
            axis(app.UIAxes, 'off');
            
            image(app.UIAxes, app.map_i, 'CDataMapping', 'scaled');
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            pca_map = reshape(app.map, [], size(app.map, 3));

            [~, score, ~, ~,explained] = pca(pca_map);
                        
            
            gscatter(app.UIAxes3, score(:,1), score(:,2));
            xlabel(app.UIAxes3, ['Principal Component 1 (' num2str(explained(1)) '%)']);
            ylabel(app.UIAxes3, ['Principal Component 2 (' num2str(explained(2)) '%)']);
            title(app.UIAxes3, 'PCA Score Plot');
        end

        % Value changed function: RamanShiftEditField
        function RamanShiftEditFieldValueChanged(app, event)
            value = app.RamanShiftEditField.Value;

            x_num = app.X_NumEditField.Value;

            y_num = app.Y_NumEditField.Value;
            
            shift = app.RamanShiftEditField.Value;
            app.Slider.Value=app.RamanShiftEditField.Value;
            
            i_RS = find(app.RS < shift +20 & app.RS > shift - 20);
            app.map_i = squeeze(mean(app.map(:,:,i_RS),3));
            % Set the x-axis and y-axis limits
            xlim(app.UIAxes, [0 x_num]);
            ylim(app.UIAxes, [0 y_num]);
            
            % Set the title of the UIAxes component
            title(app.UIAxes, ['Raman Map ' num2str(shift-20) ' - ' num2str(shift+20) '  cm^{-1}']);
            
            % Turn off the axis
            axis(app.UIAxes, 'off');
            
            image(app.UIAxes, app.map_i, 'CDataMapping', 'scaled');
        end

        % Callback function
        function SaveMapButtonPushed(app, event)
            % Get a handle to the figure that contains the UIAxes component
            fig = app.UIAxes.Parent.Parent;
            
            % Prompt the user to select a file name and location to save the PNG image
            [filename, pathname] = uiputfile('*.png', 'Save as PNG file');
            
            % Check if the user canceled the save operation
            if isequal(filename,0) || isequal(pathname,0)
                % User canceled the save operation, do nothing
                return
            end
            
            % Set the file path and name for the PNG image
            filepath = fullfile(pathname, filename);
            
            % Get the content of the UIAxes component as an image
            img = print(fig, '-dpng', '-r300');
            
            % Write the image to file
            imwrite(img, filepath);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x_pos=app.x_coordEditField.Value;
            y_pos=app.y_coordEditField.Value;

            spectrum = squeeze (app.map(y_pos,x_pos,:));

            plot(app.UIAxes2, app.RS, spectrum)
            xlabel(app.UIAxes2, 'Raman Shift');
            ylabel(app.UIAxes2, 'Normalised Intensity (AU)');
            xlim(app.UIAxes2, [200 4000]);
            title(app.UIAxes2, ['Raman Spectrum at position (' num2str(x_pos) ',' num2str(y_pos) ') of the Map']);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'PCA Score Plot')
            xlabel(app.UIAxes3, 'Principal Component 1')
            ylabel(app.UIAxes3, 'Principal Component 2 ')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Raman Spectrum at position (NaN, NaN) of the Map')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raman Map')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImportBackgroundDataButton = uibutton(app.UIFigure, 'push');
            app.ImportBackgroundDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBackgroundDataButtonPushed, true);
            app.ImportBackgroundDataButton.Text = 'Import Background Data';
            app.MapButton = uibutton(app.UIFigure, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Text = 'Map';
            app.X_StartEditFieldLabel = uilabel(app.UIFigure);
            app.X_StartEditFieldLabel.Text = 'X_Start';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [300 4000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 300;
            app.X_StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_StepEditFieldLabel = uilabel(app.UIFigure);
            app.X_StepEditFieldLabel.Text = 'X_Step';
            app.X_StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_NumEditFieldLabel = uilabel(app.UIFigure);
            app.X_NumEditFieldLabel.Text = 'X_Num';
            app.X_NumEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_StartEditFieldLabel = uilabel(app.UIFigure);
            app.Y_StartEditFieldLabel.Text = 'Y_Start';
            app.Y_StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_StepEditFieldLabel = uilabel(app.UIFigure);
            app.Y_StepEditFieldLabel.Text = 'Y_Step';
            app.Y_StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_NumEditFieldLabel = uilabel(app.UIFigure);
            app.Y_NumEditFieldLabel.Text = 'Y_Num';
            app.Y_NumEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_coordEditFieldLabel = uilabel(app.UIFigure);
            app.x_coordEditFieldLabel.Text = 'x_coord';
            app.x_coordEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCAButton = uibutton(app.UIFigure, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA';
            app.MapFolderButton = uibutton(app.UIFigure, 'push');
            app.MapFolderButton.ButtonPushedFcn = createCallbackFcn(app, @MapFolderButtonPushed, true);
            app.MapFolderButton.Text = 'Map Folder';
            app.folderPathTextbox = uieditfield(app.UIFigure, 'text');
            app.RamanShiftEditFieldLabel = uilabel(app.UIFigure);
            app.RamanShiftEditFieldLabel.Text = 'Raman Shift';
            app.RamanShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.RamanShiftEditField.ValueChangedFcn = createCallbackFcn(app, @RamanShiftEditFieldValueChanged, true);
            app.y_coordEditFieldLabel = uilabel(app.UIFigure);
            app.y_coordEditFieldLabel.Text = 'y_coord';
            app.y_coordEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time ';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReadoutRateEditFieldLabel = uilabel(app.UIFigure);
            app.ReadoutRateEditFieldLabel.Text = 'Readout Rate';
            app.ReadoutRateEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end